classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FrequencyResponce        matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        AddPanel                 matlab.ui.container.Panel
        RealLabel                matlab.ui.control.Label
        RealEditField            matlab.ui.control.NumericEditField
        ImaginaryEditFieldLabel  matlab.ui.control.Label
        ImaginaryEditField       matlab.ui.control.NumericEditField
        TypeLabel                matlab.ui.control.Label
        TypeSwitch               matlab.ui.control.Switch
        ADDButton                matlab.ui.control.Button
        RefreshButton            matlab.ui.control.Button
        PlotSettingButtonGroup   matlab.ui.container.ButtonGroup
        MagnitudeLinearButton    matlab.ui.control.RadioButton
        MagnitudedBButton        matlab.ui.control.RadioButton
        PhaseargButton           matlab.ui.control.RadioButton
        PhaseARGButton           matlab.ui.control.RadioButton
        Information              matlab.ui.container.Panel
        zero                     matlab.ui.control.Label
        pole                     matlab.ui.control.Label
        FinishButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        poles = [];
        zeros = [];
    end
    
    methods (Access = private)
        
        function results = process(app)
            app.FrequencyResponce.Name = 'Frequency Responce';
             hold( app.UIAxes,'off');
             grid (app.UIAxes , 'on');
             title(app.UIAxes , 'Frequency Response');
             app.UIAxes.YLabel.String = 'Magnitude(Linear)';
             app.UIAxes.XLabel.String = 'Normalized Frequency (\times\pi rad/sample)';
             app.zero.Text = "Number of zeros : " + string(length(app.zeros));
             app.pole.Text = "Number of poles : " + string(length(app.poles));
             [b , a ] = zp2tf((app.zeros)' , app.poles , 1);
             [h , w]= freqz(b , a , -pi : pi/128:pi);
             p = abs(h);
            
            switch app.PlotSettingButtonGroup.SelectedObject
                 case app.MagnitudeLinearButton
                     app.UIAxes.YLabel.String = 'Magnitude(Linear)';
                     p = abs(h);
                 case app.MagnitudedBButton
                     app.UIAxes.YLabel.String = 'Magnitude(dB)';                     
                     p = 10 * log(abs(h));
                 case app.PhaseargButton
                     app.UIAxes.YLabel.String = 'Phase(arg)'; 
                     p = unwrap(angle(h));
                 case app.PhaseARGButton
                     app.UIAxes.YLabel.String = 'Phase(ARG)'; 
                     p = angle(h);
             end
             plot(app.UIAxes ,w/pi , p);
            
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            process(app)
        end

        % Selection changed function: PlotSettingButtonGroup
        function PlotSettingButtonGroupSelectionChanged(app, event)
         %   selectedButton = app.PlotSettingButtonGroup.SelectedObject;
             [b , a ] = zp2tf((app.zeros)' , app.poles , 1);
             [h , w]= freqz(b , a , -pi : pi/128:pi);
             p = abs(h);
            
            switch app.PlotSettingButtonGroup.SelectedObject
                 case app.MagnitudeLinearButton
                     app.UIAxes.YLabel.String = 'Magnitude(Linear)';
                     p = abs(h);
                 case app.MagnitudedBButton
                     app.UIAxes.YLabel.String = 'Magnitude(dB)';                     
                     p = 10 * log(abs(h));
                 case app.PhaseargButton
                     app.UIAxes.YLabel.String = 'Phase(arg)'; 
                     p = unwrap(angle(h));
                 case app.PhaseARGButton
                     app.UIAxes.YLabel.String = 'Phase(ARG)'; 
                     p = angle(h);
             end
             plot(app.UIAxes ,w/pi , p);
        end

        % Button pushed function: ADDButton
        function ADDButtonPushed(app, event)
            realx = app.RealEditField.Value;
            imgx =app.ImaginaryEditField.Value;
            type = app.TypeSwitch.Value;
          %  if realx ~= 0 || imgx ~= 0 
            switch type
                case 'Pole'
                    app.poles(length(app.poles) + 1)= realx+1i*imgx;
                case 'Zero'
                    app.zeros(length(app.zeros) + 1)= realx+1i*imgx;
            end
           % app.EditField.Value = 'pole ' + string(app.poles(length(app.poles))) ;
             [b , a ] = zp2tf((app.zeros)' , app.poles , 1);
             [h , w]= freqz(b , a , -pi : pi/128:pi);
             p = abs(h);
             app.zero.Text = "Number of zeros : " + string(length(app.zeros));
             app.pole.Text = "Number of poles : " + string(length(app.poles));
%              app.zero.Text = "Number of zeros : " + string(app.numzero);
%              app.pole.Text = "Number of poles : " + string(app.numpole);            
            switch app.PlotSettingButtonGroup.SelectedObject
                 case app.MagnitudeLinearButton
                     app.UIAxes.YLabel.String = 'Magnitude(Linear)';
                     p = abs(h);
                 case app.MagnitudedBButton
                     app.UIAxes.YLabel.String = 'Magnitude(dB)';                     
                     p = 10 * log(abs(h));
                 case app.PhaseargButton
                     app.UIAxes.YLabel.String = 'Phase(arg)'; 
                     p = unwrap(angle(h));
                 case app.PhaseARGButton
                     app.UIAxes.YLabel.String = 'Phase(ARG)'; 
                     p = angle(h);
             end
             plot(app.UIAxes ,w/pi , p);
            %+ 'zero '+ string(app.zeros(length(app.zeros) + 1));
           % end
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.zeros = [];
            app.poles = [];
                         [b , a ] = zp2tf((app.zeros)' , app.poles , 1);
             [h , w]= freqz(b , a , -pi : pi/128:pi);
             p = abs(h);
             app.zero.Text = "Number of zeros : " + string(length(app.zeros));
             app.pole.Text = "Number of poles : " + string(length(app.poles));
%              app.zero.Text = "Number of zeros : " + string(app.numzero);
%              app.pole.Text = "Number of poles : " + string(app.numpole);            
            switch app.PlotSettingButtonGroup.SelectedObject
                 case app.MagnitudeLinearButton
                     app.UIAxes.YLabel.String = 'Magnitude(Linear)';
                     p = abs(h);
                 case app.MagnitudedBButton
                     app.UIAxes.YLabel.String = 'Magnitude(dB)';                     
                     p = 10 * log(abs(h));
                 case app.PhaseargButton
                     app.UIAxes.YLabel.String = 'Phase(arg)'; 
                     p = unwrap(angle(h));
                 case app.PhaseARGButton
                     app.UIAxes.YLabel.String = 'Phase(ARG)'; 
                     p = angle(h);
             end
             plot(app.UIAxes ,w/pi , p);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FrequencyResponce = uifigure;
            app.FrequencyResponce.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.FrequencyResponce);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AddPanel = uipanel(app.FrequencyResponce);
            app.AddPanel.Title = 'Add';
            app.RealLabel = uilabel(app.AddPanel);
            app.RealLabel.Text = 'Real      ';
            app.RealEditField = uieditfield(app.AddPanel, 'numeric');
            app.ImaginaryEditFieldLabel = uilabel(app.AddPanel);
            app.ImaginaryEditFieldLabel.Text = 'Imaginary';
            app.ImaginaryEditField = uieditfield(app.AddPanel, 'numeric');
            app.TypeLabel = uilabel(app.AddPanel);
            app.TypeLabel.Text = 'Type            ';
            app.TypeSwitch = uiswitch(app.AddPanel, 'slider');
            app.TypeSwitch.Items = {'Pole', 'Zero'};
            app.TypeSwitch.Value = 'Pole';
            app.ADDButton = uibutton(app.AddPanel, 'push');
            app.ADDButton.ButtonPushedFcn = createCallbackFcn(app, @ADDButtonPushed, true);
            app.ADDButton.Text = 'ADD';
            app.RefreshButton = uibutton(app.AddPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.PlotSettingButtonGroup = uibuttongroup(app.FrequencyResponce);
            app.PlotSettingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotSettingButtonGroupSelectionChanged, true);
            app.PlotSettingButtonGroup.Title = 'Plot Setting';
            app.MagnitudeLinearButton = uiradiobutton(app.PlotSettingButtonGroup);
            app.MagnitudeLinearButton.Text = 'Magnitude(Linear)';
            app.MagnitudeLinearButton.Value = true;
            app.MagnitudedBButton = uiradiobutton(app.PlotSettingButtonGroup);
            app.MagnitudedBButton.Text = 'Magnitude(dB)';
            app.PhaseargButton = uiradiobutton(app.PlotSettingButtonGroup);
            app.PhaseargButton.Text = 'Phase(arg)';
            app.PhaseARGButton = uiradiobutton(app.PlotSettingButtonGroup);
            app.PhaseARGButton.Text = 'Phase(ARG)';
            app.Information = uipanel(app.FrequencyResponce);
            app.Information.Title = 'Information';
            app.zero = uilabel(app.Information);
            app.pole = uilabel(app.Information);
            app.pole.Text = 'Label2';
            app.FinishButton = uibutton(app.FrequencyResponce, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FrequencyResponce)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FrequencyResponce)
        end
    end
end